classdef HW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuratKILCIUIFigure              matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        logout_logo                     matlab.ui.control.Image
        github_logo                     matlab.ui.control.Image
        linkedin_logo                   matlab.ui.control.Image
        instagram_logo                  matlab.ui.control.Image
        youtube_logo                    matlab.ui.control.Image
        help_logo                       matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        uni_logo                        matlab.ui.control.Image
        HW1Tab                          matlab.ui.container.Tab
        grayscaleSwitch                 matlab.ui.control.Switch
        grayscaleSwitchLabel            matlab.ui.control.Label
        COLLUMNKnob                     matlab.ui.control.Knob
        COLLUMNKnobLabel                matlab.ui.control.Label
        ROWKnob                         matlab.ui.control.Knob
        ROWKnobLabel                    matlab.ui.control.Label
        ChooseImageButton               matlab.ui.control.Button
        ConvertButton                   matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        HW2Tab                          matlab.ui.container.Tab
        CROPIMAGEY2EditField            matlab.ui.control.NumericEditField
        CROPIMAGEY2EditFieldLabel       matlab.ui.control.Label
        CROPIMAGEX2EditField            matlab.ui.control.NumericEditField
        CROPIMAGEX2EditFieldLabel       matlab.ui.control.Label
        CROPIMAGEY1EditField            matlab.ui.control.NumericEditField
        CROPIMAGEY1EditFieldLabel       matlab.ui.control.Label
        CROPIMAGEX1EditField            matlab.ui.control.NumericEditField
        CROPIMAGEX1EditFieldLabel       matlab.ui.control.Label
        RESIZEIMAGEHEIGHTEditField      matlab.ui.control.NumericEditField
        RESIZEIMAGEHEIGHTEditFieldLabel  matlab.ui.control.Label
        RESIZEIMAGEWIDTHEditField       matlab.ui.control.NumericEditField
        RESIZEIMAGEWIDTHEditFieldLabel  matlab.ui.control.Label
        SHIFTIMAGEDOWNEditField         matlab.ui.control.NumericEditField
        SHIFTIMAGEDOWNEditFieldLabel    matlab.ui.control.Label
        SHIFTIMAGERIGHTEditField        matlab.ui.control.NumericEditField
        SHIFTIMAGERIGHTEditFieldLabel   matlab.ui.control.Label
        RGBHSIButton                    matlab.ui.control.Button
        CROPButton                      matlab.ui.control.Button
        RESIZEButton                    matlab.ui.control.Button
        SHIFTButton                     matlab.ui.control.Button
        REFLECTButton                   matlab.ui.control.Button
        REFLECTButtonGroup              matlab.ui.container.ButtonGroup
        CROSSEDButton                   matlab.ui.control.RadioButton
        VERTICALButton                  matlab.ui.control.RadioButton
        HORIZONTALButton                matlab.ui.control.RadioButton
        CHOOSEFILEButton                matlab.ui.control.Button
        outputImage                     matlab.ui.control.UIAxes
        inputImage                      matlab.ui.control.UIAxes
        HW3Tab                          matlab.ui.container.Tab
        ShowHistogramButton             matlab.ui.control.Button
        BlackLevelSlider                matlab.ui.control.Slider
        BlackLevelSliderLabel           matlab.ui.control.Label
        WhiteLevelSlider                matlab.ui.control.Slider
        WhiteLevelSliderLabel           matlab.ui.control.Label
        HistogramEqualizationButton     matlab.ui.control.Button
        HistogramStretchingButton       matlab.ui.control.Button
        TransferFunctionConvertButton   matlab.ui.control.Button
        SelectPictureButton             matlab.ui.control.Button
        hw3OutputImage                  matlab.ui.control.Image
        hw3InputImage                   matlab.ui.control.Image
        HW4Tab                          matlab.ui.container.Tab
        MaskImageButton                 matlab.ui.control.Button
        MaskButton                      matlab.ui.control.Button
        SobelSmoothButton               matlab.ui.control.Button
        SobelGradientButton             matlab.ui.control.Button
        SharpenedImageButton            matlab.ui.control.Button
        LaplacianButton                 matlab.ui.control.Button
        COLUMNLabel                     matlab.ui.control.Label
        ROWLabel                        matlab.ui.control.Label
        hw4_image                       matlab.ui.control.Image
        hw4_image_output                matlab.ui.control.Image
        MinRowFilterEditField           matlab.ui.control.NumericEditField
        MinColumnFilterEditField        matlab.ui.control.NumericEditField
        SelectPictureButton_2           matlab.ui.control.Button
        MinimumFilterButton             matlab.ui.control.Button
        MaximumFilterButton             matlab.ui.control.Button
        AverageFilterButton             matlab.ui.control.Button
        MedianFilterButton              matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        HW5Tab                          matlab.ui.container.Tab
        BUTTERWORTHNOTCHREJECTFILTERButton  matlab.ui.control.Button
        BUTTERWORTHHIGHPASSFILTERButton  matlab.ui.control.Button
        CHOOSEIMAGEButton               matlab.ui.control.Button
        hw5_img1                        matlab.ui.control.UIAxes
        hw5_img2                        matlab.ui.control.UIAxes
        hw5_input_img                   matlab.ui.control.UIAxes
        HW6Tab                          matlab.ui.container.Tab
        AdaptivefilterButton            matlab.ui.control.Button
        GeometricMeanFilterButton       matlab.ui.control.Button
        HarmonicFilterButton            matlab.ui.control.Button
        MinfilterButton                 matlab.ui.control.Button
        MaxFilterButton                 matlab.ui.control.Button
        MedianFilterButton_2            matlab.ui.control.Button
        MeanFilterButton                matlab.ui.control.Button
        ColumnSpinner                   matlab.ui.control.Spinner
        ColumnSpinnerLabel              matlab.ui.control.Label
        RowSpinner                      matlab.ui.control.Spinner
        RowSpinnerLabel                 matlab.ui.control.Label
        SelectPictureButton_3           matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        HW7Tab                          matlab.ui.container.Tab
        SelectImageButton               matlab.ui.control.Button
        Image73Button                   matlab.ui.control.Button
        Image72Button                   matlab.ui.control.Button
        Image71Button                   matlab.ui.control.Button
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        HW8Tab                          matlab.ui.container.Tab
        SegmentationButton              matlab.ui.control.Button
        MorphologicalGradientButton     matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        thresholdvalue                  matlab.ui.control.NumericEditField
        kernelsize                      matlab.ui.control.NumericEditField
        ThresholdedThatButton           matlab.ui.control.Button
        Close30Open100Gradient3Button   matlab.ui.control.Button
        THatTransformationButton        matlab.ui.control.Button
        ThresholdingButton              matlab.ui.control.Button
        ClosingButton                   matlab.ui.control.Button
        OpeningButton                   matlab.ui.control.Button
        ErosionButton                   matlab.ui.control.Button
        DilationButton                  matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        FilteredImageLabel              matlab.ui.control.Label
        OriginalImageLabel              matlab.ui.control.Label
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageData % Description
        reflectImage % Description
        filepath % Description
        readingfile % Description
        picture % Description
        image_hw4 % Description
        image_hw4_row % Description
        image_hw4_column % Description
        image1 % Description
        image2 % Description
        filterbound1 % Description
        filterbound2 % Description
        image3 % Description
    end
    methods (Access = private)
        
        function results = high_pass(~,new_images)
            [row, col] = size(new_images);
            fftI = fftshift(fft2(new_images,2*row-1,2*col-1));                     
            n = 4;            
            d0= 50;
            
            % Initialize filter.
            high_pass_filter_low = zeros(2*row-1,2*col-1);
            high_pass_filter_high = zeros(2*row-1,2*col-1);       
            for i = 1:2*row-1
                for j =1:2*col-1                   
                    dist = sqrt((i-(row+1))^2 + (j-(col+1))^2);
                    high_pass_filter_low(i,j) = 1/(1 + (dist/d0)^(2*n));
                    high_pass_filter_high(i,j)= 1.0 - high_pass_filter_low(i,j);
                     
                end
            end
            high_passs = high_pass_filter_high.*fftI;
            high_passs = ifftshift(high_passs);
            high_passs = ifft2(high_passs,2*row-1,2*col-1);
            high_passs = real(high_passs(1:row,1:col));
            results = high_passs;
        end
        
        function results = threshold(~,new_images)
            [row, col] = size(new_images);
            fftI = fftshift(fft2(new_images,2*row-1,2*col-1));                     
            n = 4;            
            d0= 50;
            
            % Initialize filter.
            high_pass_filter_low = zeros(2*row-1,2*col-1);
            high_pass_filter_high = zeros(2*row-1,2*col-1);       
            for i = 1:2*row-1
                for j =1:2*col-1                   
                    dist = sqrt((i-(row+1))^2 + (j-(col+1))^2);
                    high_pass_filter_low(i,j) = 1/(1 + (dist/d0)^(2*n));
                    high_pass_filter_high(i,j)= 1.0 - high_pass_filter_low(i,j);
                     
                end
            end
            high_passs = high_pass_filter_high.*fftI;
            high_passs = ifftshift(high_passs);
            high_passs = ifft2(high_passs,2*row-1,2*col-1);
            high_passs = real(high_passs(1:row,1:col));
            thresholding_image = new_images .* high_passs;
            results = thresholding_image;
            
        end
                
        function results = Laplace(~,new_images)
            [row,column] = size(new_images);
            img = zeros(row,column);
            new_images = double(new_images);
            F1 = [0 1 0;1 -4 1; 0 1 0];
            for i = 1:row-2
                for j = 1:column-2                    
                    dotProduct = F1 * new_images(i:i+2, j:j+2);
                    summationResult = sum(dotProduct(:));
                    img(i + 1, j + 1) = summationResult;
                end
            end
            results = img;
        end
        
        function sobel_filters = sobel(~,new_images)
            kernel_x = [1.0 0.0 -1.0; 2.0 0.0 -2.0; 1.0 0.0 -1.0];
            kernel_y = [1.0 2.0 1.0; 0.0 0.0 0.0; -1.0 -2.0 -1.0];
            [rows, columns] = size(new_images);
            sobel_filters = zeros(rows, columns);            
            for i = 1:rows - 2
                for j = 1:columns - 2
                    gx = sum(sum(kernel_x .* new_images(i:i+2, j:j+2)));
                    gy = sum(sum(kernel_y .* new_images(i:i+2, j:j+2)));
                    sobel_filters(i + 1, j + 1) = sqrt(gx^2 + gy^2);
                end
            end            
            sobel_filters = uint8(sobel_filters);
        end
                
        function results = sobel_mask(~,new_images)
            results = imgaussfilt(new_images,5);            
        end

        function results = MinFilter(~,image,row_value,column_value)
            [row,column]  = size(image);
            new_image = zeros(row,column);           
            for i = 1:row-row_value
                for j = 1:column-column_value
                    new_image(i+1,j+1) = min(min(image(i:i+row_value-1,j:j+column_value-1)));
                end
            end
            results = new_image;
        end
        
        function results = MaxFilter(~,image,row_value,column_value)
            [row,column]  = size(image);
            new_image = zeros(row,column);           
            for i = 1:row-row_value
                for j = 1:column-column_value
                    new_image(i+1,j+1) = max(max(image(i:i+row_value-1,j:j+column_value-1)));
                end
            end
            results = new_image/255.0;
            
        end
        
        function results = MedianFilter(~,image,row_value,column_value)
            [row,column]  = size(image);
            new_image = zeros(row,column);        
            for i = 1:row-row_value
                for j = 1:column-column_value
                    new_image(i+1,j+1) = median(median(image(i:i+row_value-1,j:j+column_value-1)));
                end
            end
            results = new_image/255.0;
            
        end
        
        function results = AverageFilter(~,image,row_value,column_value)
            [row,column]  = size(image);
            new_image = zeros(row,column);       
            for i = 1:row-row_value
                for j = 1:column-column_value
                    new_image(i+1,j+1) = mean(mean(image(i:i+row_value-1,j:j+column_value-1)));
                end
            end
            results = new_image/255.0;
            
        end
       
        function results = WienerFilter(app,image)
            T=1; ax=30; ay=40; NSR=0; 
            u=linspace(-0.5,0.5,size(image,2));
            v=linspace(-0.5,0.5,size(image,1));
             
            [U,V]=meshgrid(u,v);
            H=(T./(pi*(U*ax+V*ay))).*sin(pi*(U*ax+V*ay)).*exp(-1i*pi*(U*ax+V*ay));
             
            I_f=fft2(image);
            I_motion_fn=fftshift(I_f);
             
            wiener=(1./H).*((H.^2)./((H.^2)+NSR));
             
            I_recon_fn=I_motion_fn.*wiener;
            I_recon=ifft2(ifftshift(I_recon_fn));
            imshow(I_recon,'Parent',app.UIAxes_5)
        end
       
        function results = add_salt_noise(app)
            global image noised
            R=salt_and_pepper(app);
            c=R==1;
            noised=image;
            noised(c)=255;
            imshow(noised,'Parent',app.UIAxes_4)      
            app.Label_2.Text="Salt Noised Image";
        end
        
        function R = salt_and_pepper(app)
            global image
            PepperDegree=app.EditField.Value;
            SaltDegree=app.EditField2.Value;
            [RowValue,ColumnValue]=size(image);
            R(1:RowValue,1:ColumnValue)=0.5;
            X=rand(RowValue,ColumnValue);
            c=find(X<=PepperDegree);
            R(c)=0;
            u=SaltDegree+PepperDegree;
            c=find(X>PepperDegree & X<=u);
            R(c)=1;           
        end
        
        function results = add_pepper_noise(app)
            global image noised
            R=salt_and_pepper(app);
            c=find(R==0);
            noised=image;
            noised(c)=0;
            imshow(noised,'Parent',app.UIAxes_4)
            app.Label_2.Text="Pepper Noised Image"
        end
        
        function results = add_periodic_noise(app)
            global image
            
            [row,column,band]=size(image);
            if band>1
                image=rgb2gray(image);
            end
            RowVector=(1:column);
            period=10;
            amplitude=0.5;
            offset=1-amplitude;
            cosvector=amplitude * (1 + cos(2 * pi * RowVector / period))/2 + offset;
            ripplesImage = repmat(cosvector,[row,1]);
            minValue = min(min(ripplesImage))
            maxValue = max(max(ripplesImage))
            
            im = ripplesImage .* double(image);
            imshow(im,'Parent',app.UIAxes_4);
            
        end
        
        function results = add_gaussian_noise(app)
            global image rgb noised
            mean=app.EditField.Value;
            variance=app.EditField2.Value;
            if(rgb==3)
                image = rgb2gray(image);
            end
            DoubledImage = im2double(image);
            noised= DoubledImage + sqrt(variance)*randn(size(DoubledImage)) + mean;
            imshow(noised,'Parent',app.UIAxes_4);
            app.Label_2.Text="Gaussian Noised Image"
        end
        
        function output = blur(app)
            global image noised
            w=app.EditField.Value;
            B=double(image);
            [m,n] = size(B);
            k=2*w+1;
            for i = 1:m
                for j = 1:n
                    p=i-fix(k/2);
                    q=i+fix(k/2);
                    r=j-fix(k/2);
                    s=j+fix(k/2);
                    if p<1
                        p=1;
                    end
                    if q>m
                        q=m;
                    end
                    if r<1
                        r=1;
                    end
                    if s>n
                        s=n;
                    end
                    A=B([p:q],[r:s]);
                    C(i,j)=mean(A(:));
                end
            end
            noised=uint8(C);
            imshow(noised,'Parent',app.UIAxes_4)
  

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.*','Pick an Image');
            image = strcat(pathname,filename);
            a=imread(image);
            imshow(a, 'Parent', app.UIAxes);
            app.imageData =a;

        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
          
            image = app.imageData;
            im = blurImage(image,app.ROWKnob.Value, app.COLLUMNKnob.Value);            

            if app.grayscaleSwitch.Value == "On"
                gray = rgb2gray(im);
                imshow(gray, 'Parent', app.UIAxes2);
            else
                imshow(im, 'Parent', app.UIAxes2);
            end
        end

        % Button pushed function: REFLECTButton
        function REFLECTButtonPushed(app, event)
            img = app.imageData;
            if(app.VERTICALButton.Value == 1)
                app.picture=img(end:-1:1, :, :);
                imshow(app.picture, 'Parent', app.outputImage);
            end
            if(app.HORIZONTALButton.Value == 1)
                app.picture=img(:, end:-1:1, :);
                imshow(app.picture, 'Parent', app.outputImage);
            end
            if(app.CROSSEDButton.Value == 1)
                app.picture=img(end:-1:1, end:-1:1, :);
                imshow(app.picture, 'Parent', app.outputImage);
            end
        end

        % Button pushed function: CHOOSEFILEButton
        function CHOOSEFILEButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.*','Pick an Image');
            image = strcat(pathname,filename);
            a=imread(image);
            imshow(a, 'Parent', app.inputImage);
            app.imageData =a;


        end

        % Button pushed function: SHIFTButton
        function SHIFTButtonPushed(app, event)
            img = app.imageData;
            [w,h,r]=size(img); 
	        B=zeros(w,h,r); 
	        for i = 1 + app.SHIFTIMAGEDOWNEditField.Value:w
                for j = 1 + app.SHIFTIMAGERIGHTEditField.Value:h
                    for m = 1:r
                        % check if the shifted pixels are within the bounds of the image
                        if i <= w && j <= h
                            B(i, j, m) = img(i - app.SHIFTIMAGEDOWNEditField.Value, j - app.SHIFTIMAGERIGHTEditField.Value, m);
                        else
                            B(i, j, m) = 0;
                        end
                    end
                end
            end
            B= uint8(B);
            imshow(B, 'Parent', app.outputImage);

        end

        % Button pushed function: RESIZEButton
        function RESIZEButtonPushed(app, event)
            img = app.imageData;
            
            [rowImage,columnImage,channelImage] = size(img);
            zeroArray=zeros(app.RESIZEIMAGEWIDTHEditField.Value,app.RESIZEIMAGEWIDTHEditField.Value,channelImage);
            
            resize_factor_row = rowImage / app.RESIZEIMAGEWIDTHEditField.Value;
            resize_factor_column = columnImage / app.RESIZEIMAGEWIDTHEditField.Value;
            for i=1:app.RESIZEIMAGEWIDTHEditField.Value
               for j=1:app.RESIZEIMAGEWIDTHEditField.Value
                   for m=1:channelImage
                   zeroArray(i,j,m) = img(int16(i*resize_factor_row),int16(j*resize_factor_column),m);
                   end
               end
            end
            image = uint8(zeroArray);
            
            imshow(image, 'Parent', app.outputImage);
        end

        % Button pushed function: RGBHSIButton
        function RGBHSIButtonPushed(app, event)
            img = app.imageData;
            I = double(img) / 255;
            HSV = rgb2hsv(img);
            H = HSV(:,:,1);
            S = HSV(:,:,2);          
            % Intensity
            I = sum(I, 3)./3;          
            % Creating the HSL Image
            HSI = zeros(size(img));
            HSI(:,:,1) = H;
            HSI(:,:,2) = S;
            HSI(:,:,3) = I;
            image = HSI;
            imshow(image, 'Parent', app.outputImage);
        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            img = app.imageData;
            [row_image,column_image,channel_image] = size(img);
            zeroArray = zeros(row_image,column_image,channel_image);
            for i = app.CROPIMAGEX1EditField.Value:app.CROPIMAGEX2EditField.Value
                for j = app.CROPIMAGEY1EditField.Value:app.CROPIMAGEY2EditField.Value
                    for m=1:channel_image
                        zeroArray(i,j,m) = img(i,j,m);
                    end
                end
            end
            image = uint8(zeroArray);
            imshow(image, 'Parent', app.outputImage);
        end

        % Button pushed function: SelectPictureButton
        function SelectPictureButtonPushed(app, event)
            [file,path] = uigetfile({'*'},'Pick an Image');
            imagea=fullfile(path,file);
            app.hw3InputImage.Visible=1;
            image=imread(imagea);
            sizeimage=size(image);
            if length(sizeimage)~=3
                zero=zeros(sizeimage(1),sizeimage(2),3);
                for i=1:sizeimage(1)
                    for j=1:sizeimage(2)
                        zero(i,j,1)=image(i,j);
                        zero(i,j,2)=image(i,j);
                        zero(i,j,3)=image(i,j);
                        
                    end
                end
                image=uint8(zero);
                
            end
            app.hw3InputImage.ImageSource=image;
        end

        % Button pushed function: HistogramStretchingButton
        function HistogramStretchingButtonPushed(app, event)
            image=app.hw3InputImage.ImageSource;
            sizeimage=size(image);
            image_red=image(:,:,1);
            image_green=image(:,:,2);
            image_blue=image(:,:,3);
            
            min_red=min(min(image_red));
            max_red=max(max(image_red));
            min_green=min(min(image_green));
            max_green=max(max(image_green));
            min_blue=min(min(image_blue));
            max_blue=max(max(image_blue));

            for i=1:sizeimage(1)
                for j=1:sizeimage(2)
                        image_reds=((250/(max_red-min_red))*(image_red(i,j)-min_red));
                        image_greens=((250/(max_green-min_green))*(image_green(i,j)-min_green));
                        image_blues=((250/(max_blue-min_blue))*(image_blue(i,j)-min_blue));
                        lastimage(i,j,1)=uint8(image_reds);
                        lastimage(i,j,2)=uint8(image_greens);
                        lastimage(i,j,3)=uint8(image_blues);
                        
                    
                 end
            end
            app.hw3OutputImage.ImageSource=lastimage;
            app.hw3OutputImage.Visible=1;
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            image=app.hw3InputImage.ImageSource;
            sizeimage=size(image);
            if length(sizeimage)==3
                image=rgb2gray(image);
            end
            image_size=size(image);
            image=double(image);

            % Loop for Getting the Histogram of the image
            histogram = zeros(1,256);
            for i=1:image_size(1)
                for j=1:image_size(2)
                    for k=0:255
                        if image(i,j)==k
                            histogram(k+1)=histogram(k+1)+1;
                        end
                    end
                end
            end

            %Generating PDF out of histogram by diving by total no. of pixels
            pdf=(1/(image_size(1)*image_size(2)))*histogram;
        
            %Generating CDF out of PDF
            cdf = zeros(1,256);
            cdf(1)=pdf(1);
            for i=2:256
        
                cdf(i)=cdf(i-1)+pdf(i);
            end
            cdf = round(255*cdf);
            ep = zeros(image_size);
            for i=1:image_size(1)                                   
                for j=1:image_size(2)                                  
                    t=(image(i,j)+1);                              
                    ep(i,j)=cdf(t);                             
                end                                             
            end
        
            % Loop for Getting the Histogram of the image
            histogram_2 = zeros(1,256);
            for i=1:image_size(1)
                for j=1:image_size(2)
                    for k=0:255
                        if ep(i,j)==k
                            histogram_2(k+1)=histogram_2(k+1)+1;
                        end
                    end
                end
            end
            size_ep=size(ep);
            zero=zeros(size_ep(1),size_ep(2),3);
            for i=1:size_ep(1)
                for j=1:size_ep(2)
                    zero(i,j,1)=ep(i,j);
                    zero(i,j,2)=ep(i,j);
                    zero(i,j,3)=ep(i,j);
                    
                end
            end
                
            app.hw3OutputImage.ImageSource=uint8(zero);
            app.hw3OutputImage.Visible=1;
        end

        % Button pushed function: TransferFunctionConvertButton
        function TransferFunctionConvertButtonPushed(app, event)
            blacklevel=app.BlackLevelSlider.Value;
            whitelevel=app.WhiteLevelSlider.Value;
            image=app.hw3InputImage.ImageSource;
            sizeimage=size(image);
            
            b=size(image);
            image=double(image);

            if length(sizeimage)==3
                hist1r = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if image(i,j,1)==k
                                hist1r(k+1)=hist1r(k+1)+1;
                            end
                        end
                    end
                end
                hist1g = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if image(i,j,2)==k
                                hist1g(k+1)=hist1g(k+1)+1;
                            end
                        end
                    end
                end
                hist1b = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if image(i,j,3)==k
                                hist1b(k+1)=hist1b(k+1)+1;
                            end
                        end
                    end
                end
                ep1 =image(:,:,1);
                for i=1:b(1)
                    for j=1:b(2)
                        if ep1(i,j)<blacklevel
                            ep1(i,j)=0;
        
                        elseif ep1(i,j)>whitelevel
                            ep1(i,j)=255;
                        end
                        ep(i,j,1)=ep1(i,j);
                    end
                end
                ep2 =image(:,:,2);
                for i=1:b(1)
                    for j=1:b(2)
                        if ep2(i,j)<blacklevel
                            ep2(i,j)=0;
                        elseif ep2(i,j)>whitelevel
                            ep2(i,j)=255;
                        end
                        ep(i,j,2)=ep2(i,j);
                    end
                end
                ep3=image(:,:,3);
                for i=1:b(1)
                    for j=1:b(2)
                        if ep3(i,j)<blacklevel
                            ep3(i,j)=0;
                        elseif ep3(i,j)>whitelevel
                            ep3(i,j)=255;
                        end
                        ep(i,j,3)=ep3(i,j);
                    end
                end
        
        
            app.hw3OutputImage.ImageSource=uint8(ep);
            else 
                hist1 = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if image(i,j)==k
                                hist1(k+1)=hist1(k+1)+1;
                            end
                        end
                    end
                end
                ep =image;
                for i=1:b(1)
                    for j=1:b(2)
                        if ep(i,j)<blacklevel
                            ep(i,j)=0;
                        elseif ep(i,j)>whitelevel
                            ep(i,j)=255;
                        end
                    end
                end
        
        
                hist2 = zeros(1,256);
                for i=1:b(1)
                    for j=1:b(2)
                        for k=0:255
                            if ep(i,j)==k
                                hist2(k+1)=hist2(k+1)+1;
                            end
                        end
                    end
                end
                app.hw3OutputImage.ImageSource=uint8(ep);
            end
        
            app.hw3OutputImage.Visible=1;
        end

        % Button pushed function: ShowHistogramButton
        function ShowHistogramButtonPushed(app, event)
            image=app.hw3OutputImage.ImageSource;
            figure;
            imhist(image);
        end

        % Button pushed function: SelectPictureButton_2
        function SelectPictureButton_2Pushed(app, event)
            [file,path] = uigetfile({'*'},'Pick an Image');
            imagea=fullfile(path,file);
            app.hw4_image.Visible=1;
            image=imread(imagea);
            app.image_hw4 = image;
            [app.image_hw4_row,app.image_hw4_column,~] = size(image);
            sizeimage=size(image);
            if length(sizeimage)~=3
                zero=zeros(sizeimage(1),sizeimage(2),3);
                for i=1:sizeimage(1)
                    for j=1:sizeimage(2)
                        zero(i,j,1)=image(i,j);
                        zero(i,j,2)=image(i,j);
                        zero(i,j,3)=image(i,j);
                        
                    end
                end
                image=uint8(zero);
                
            end
            app.hw4_image.ImageSource=image;

        end

        % Button pushed function: MinimumFilterButton
        function MinimumFilterButtonPushed(app, event)
            minimum_row=app.MinRowFilterEditField.Value;
             minimum_col = app.MinColumnFilterEditField.Value;
             image = app.hw4_image.ImageSource;
             k=floor(minimum_row/2);
             l=floor(minimum_col/2);
             [row,column,channel] = size(image);
             minimum_image = zeros(row+2*k,column+2*l,channel);
             for i = 1+k:row-k
                 for j = 1+l:column-l
                     for m = 1:3
                        minimum_image(i+k,j+l,m) = min(min(image(i-k:i+k,j-l:j+l,m)));
                     end
                 end
             end
             minimum_image=uint8(minimum_image);
             app.hw4_image_output.ImageSource=uint8(minimum_image);
             app.hw4_image_output.Visible=1;
        end

        % Button pushed function: MaximumFilterButton
        function MaximumFilterButtonPushed(app, event)
             minimum_row=app.MinRowFilterEditField.Value;
             minimum_col = app.MinColumnFilterEditField.Value;
             image = app.hw4_image.ImageSource;
             k=floor(minimum_row/2);
             l=floor(minimum_col/2);
             [row,column,channel] = size(image);
             minimum_image = zeros(row+2*k,column+2*l,channel);
             for i = 1+k:row-k
                 for j = 1+l:column-l
                     for m = 1:3
                        minimum_image(i+k,j+l,m) = max(max(image(i-k:i+k,j-l:j+l,m)));
                     end
                 end
             end
             minimum_image=uint8(minimum_image);
             app.hw4_image_output.ImageSource=uint8(minimum_image);
             app.hw4_image_output.Visible=1;
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            minimum_row=app.MinRowFilterEditField.Value;
             minimum_col = app.MinColumnFilterEditField.Value;
             image = app.hw4_image.ImageSource;
             k=floor(minimum_row/2);
             l=floor(minimum_col/2);
             [row,column,channel] = size(image);
             minimum_image = zeros(row+2*k,column+2*l,channel);
             for i = 1+k:row-k
                 for j = 1+l:column-l
                     for m = 1:3
                        minimum_image(i+k,j+l,m) = median(median(image(i-k:i+k,j-l:j+l,m)));
                     end
                 end
             end
             minimum_image=uint8(minimum_image);
             app.hw4_image_output.ImageSource=uint8(minimum_image);
             app.hw4_image_output.Visible=1;
        end

        % Button pushed function: AverageFilterButton
        function AverageFilterButtonPushed(app, event)
            minimum_row=app.MinRowFilterEditField.Value;
             minimum_col = app.MinColumnFilterEditField.Value;
             image = app.hw4_image.ImageSource;
             k=floor(minimum_row/2);
             l=floor(minimum_col/2);
             [row,column,channel] = size(image);
             minimum_image = zeros(row+2*k,column+2*l,channel);
             for i = 1+k:row-k
                 for j = 1+l:column-l
                     for m = 1:3
                        minimum_image(i+k,j+l,m) = mean(mean(image(i-k:i+k,j-l:j+l,m)));
                     end
                 end
             end
             minimum_image=uint8(minimum_image);
             app.hw4_image_output.ImageSource=uint8(minimum_image);
             app.hw4_image_output.Visible=1;
        end

        % Button pushed function: MaskImageButton
        function MaskImageButtonPushed(app, event)
            image = app.image_hw4;
            sobel_array = sobel(app,double(image));
            blur_filter = sobel_mask(app,sobel_array);
            
            laplacia_array = Laplace(app,image);
            laplacia_array = uint8(abs(laplacia_array));
            shape_filter = image + laplacia_array;
        
            masked_image = bitand(blur_filter, shape_filter); 
            g_image = imadd(masked_image,image);

            imshow(g_image,[],'Parent',app.UIAxes_2);
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            image = app.image_hw4;
            laplace_image = Laplace(app,image);
            laplace_image = uint8((double(laplace_image - min(laplace_image(:))) / (max(laplace_image(:)) - min(laplace_image(:)))) * 255.9);


            imshow(laplace_image,[],'Parent',app.UIAxes_2); 
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: SharpenedImageButton
        function SharpenedImageButtonPushed(app, event)
            image = app.image_hw4;
            laplace_image = Laplace(app,image);
            laplace_image = uint8(abs(laplace_image));
            sharp_image = double(laplace_image + image);

            imshow(sharp_image/255.0,[],'Parent',app.UIAxes_2); 
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: SobelGradientButton
        function SobelGradientButtonPushed(app, event)
            image = app.image_hw4;
            sobel_image = sobel(app,double(image));

            imshow(sobel_image,'Parent',app.UIAxes_2); 
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: SobelSmoothButton
        function SobelSmoothButtonPushed(app, event)
            image = app.image_hw4;
            sobel_image = sobel(app,double(image));
            sobel_mask_image = sobel_mask(app,sobel_image);

            imshow(sobel_mask_image,'Parent',app.UIAxes_2); 
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            image = app.image_hw4;
            sobel_array = sobel(app,double(image));
            blur_filter = sobel_mask(app,sobel_array);
            
            laplacia_array = Laplace(app,image);
            laplacia_array = uint8(abs(laplacia_array));
            shape_filter = image + laplacia_array;
        
            masked_image = bitand(blur_filter, shape_filter);     
            
            imshow(masked_image,[],'Parent',app.UIAxes_2);
            app.hw4_image_output.Visible =0;
            app.UIAxes2.Visible = 1;
        end

        % Button pushed function: CHOOSEIMAGEButton
        function CHOOSEIMAGEButtonPushed(app, event)
            global imageshw5
            [filename,pathname] = uigetfile('*.*','Pick an Image');
            image = strcat(pathname,filename);
            imageshw5=imread(image);
            imshow(imageshw5, 'Parent', app.hw5_input_img);
        end

        % Button pushed function: BUTTERWORTHHIGHPASSFILTERButton
        function BUTTERWORTHHIGHPASSFILTERButtonPushed(app, event)
            global imageshw5
            image = imageshw5;
            [row, col] = size(image);
            fft_I = fftshift(fft2(image,2*row-1,2*col-1));                     
            n = 4;            
            d0= 50;
            filterlow = zeros(2*row-1,2*col-1);
            filterhigh = zeros(2*row-1,2*col-1);       
            for i = 1:2*row-1
                for j =1:2*col-1                  
                    dist = sqrt((i-(row+1))^2 + (j-(col+1))^2);
                    filterlow(i,j) = 1/(1 + (dist/d0)^(2*n));
                
                    filterhigh(i,j)= 1.0 - filterlow(i,j);
                     
                end
            end
            filtered_image = filterhigh.*fft_I;
            filtered_image = ifftshift(filtered_image);
            filtered_image = ifft2(filtered_image,2*row-1,2*col-1);
            filtered_image = real(filtered_image(1:row,1:col));
            filtered_image = uint8(filtered_image);   
            thresholding = image .* filtered_image; 
            imshow(filtered_image,'Parent',app.hw5_img1); 
            imshow(thresholding,'Parent',app.hw5_img2); 
            app.hw5_img1.Visible=1;
            app.hw5_img2.Visible=1;
        end

        % Button pushed function: BUTTERWORTHNOTCHREJECTFILTERButton
        function BUTTERWORTHNOTCHREJECTFILTERButtonPushed(app, event)
            global imageshw5 
            image = imageshw5;
            [row,col] = size(image);
            fftI = fftshift(fft2(image,2*row-1,2*col-1));
            S = log(abs(fftI));   
            [x,y]=meshgrid(-256:255,-256:255);
            D = sqrt(x.^2+y.^2);
            d0 = 3;
            W = 100;
            n = 4;    
            notch_reject_filter = 1./ (1 + ((D .* W)./(D.^2 - d0.^2)).^(2*n));
               
            for i = 1:2*row-1
                for j =1:2*col-1         
                    dist = sqrt((i-(row+1)).^2 + (j-(col+1)).^2);
                 
                    band_pass_filter = 1./ (1 + ((dist .* W)./(dist.^2 - d0.^2)).^(2*n)) ;
                 
                    filter_notch(i,j) = 1 - band_pass_filter ;
            
                end
            end
         
            filtered_image = filter_notch.*fftI; 
            filtered_image = ifftshift(filtered_image);
            filtered_image = ifft2(filtered_image,2*row-1,2*col-1);
            filtered_image = real(filtered_image(1:row,1:col));
            filtered_image = uint8(filtered_image); 
            imshow(S/max(S(:)),'Parent',app.hw5_img1); 
            imshow(filtered_image,'Parent',app.hw5_img2); 
            axis(app.hw5_img1, 'on')
            axis(app.hw5_img2, 'on')
        end

        % Image clicked function: logout_logo
        function logout_logoImageClicked(app, event)
            closereq();
        end

        % Image clicked function: github_logo
        function github_logoImageClicked(app, event)
            web('https://github.com/muratkilci', '-browser')
        end

        % Image clicked function: linkedin_logo
        function linkedin_logoImageClicked(app, event)
            web('https://www.linkedin.com/in/murat-kilci-4615961b9/', '-browser')
        end

        % Image clicked function: instagram_logo
        function instagram_logoImageClicked(app, event)
            web('https://www.instagram.com/murat_kilci', '-browser')
        end

        % Image clicked function: youtube_logo
        function youtube_logoImageClicked(app, event)
            web('https://www.youtube.com/channel/UCVx9odXaosySGaDtXX96QGA', '-browser')
        end

        % Image clicked function: help_logo
        function help_logoImageClicked(app, event)
            web mailto:murat_kilci@hotmail.com?subject='About the introductory algorithms project.'
        end

        % Image clicked function: uni_logo
        function uni_logoImageClicked(app, event)
            web('https://www.ikcu.edu.tr/', '-browser')
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            [M, N]= size(app.image1);
            Gframe=double(app.image1);
            Gframe = Gframe - min(Gframe(:));
            Gframe = Gframe / max(Gframe(:));
            function R = noises(type, M, N, a, b)
                if nargin == 1
                   a = 0; b = 1;
                   M = 1; N = 1;
                elseif nargin == 3
                   a = 0; b = 1;
                end             
                
                switch lower(type)
                case 'uniform'
                   R = a + (b - a)*rand(M, N);
                case 'gaussian'
                   R = a + b*randn(M, N);
                case 'lognormal'
                   if nargin <= 3
                      a = 1; b = 0.25;
                   end
                   R = a*exp(b*randn(M, N));
                case 'rayleigh'
                   R = a + (-b*log(1 - rand(M, N))).^0.5;
                case 'exponential'
                   if nargin <= 3
                      a = 1;
                   end
                   if a <= 0
                      error('Parameter a must be positive for exponential type.')
                   end
                   k = -1/a;
                   R = k*log(1 - rand(M, N));
                case 'erlang'
                   if nargin <= 3
                      a = 2; b = 5;
                   end
                   if (b ~= round(b) || b <= 0)
                      error('Param b must be a positive integer for Erlang.')
                   end
                   k = -1/a;
                   R = zeros(M, N);
                   for j = 1:b         
                      R = R + k*log(1 - rand(M, N));
                   end
                end
            end
             function R=saltpepper(M,N,a,b)
                    if nargin<=2
                        a=0.05;
                        b=0.05;
                    end
                    
                    R(1:M,1:N)=0.5;
                    X=rand(M,N);
                    c=find(X<=a);
                    R(c)=0;
                    u=a+b;
                    c=find(X>a & X<=u);
                    R(c)=1;
                end
            if value==1
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0.05, 0.05);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = R==1;
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==0
                app.image2=app.image1;
              imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==2
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0, 0.05);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = R==1;
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==3
                [M, N]= size(app.image1);
                R = saltpepper(M, N, 0.05, 0);
                c = find (R==0);
                gp = app.image1;
                gp(c) = 0;
                d = R==1;
                gp(d) = 255;
                app.image2=gp;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==4
                n=0.1*noises('gaussian', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==5
                n=0.3*noises('uniform', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            
            elseif value==6
                n=0.1*noises('erlang', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            elseif value==7
                n=0.3*noises('rayleigh', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
              elseif value==8
                n=0.2*noises('exponential', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
             elseif value==9
                n=0.3*noises('lognormal', M,N);
                app.image2=n+Gframe;
                imshow(app.image2,'Parent',app.UIAxes2_2)
             elseif value==10
                s = size (app.image1); % It assigns the size of t to s.
                [x, y] = meshgrid (1: s (1), 1: s (2)); % x, y dimensional matrix.
                p = sin (x / 3 + y / 5) +1; % sins
                k = (im2double (app.image1) + p / 2) / 2;
                app.image2=k;
                imshow(app.image2,'Parent',app.UIAxes2_2)
            end
            
        end

        % Button pushed function: SelectPictureButton_3
        function SelectPictureButton_3Pushed(app, event)
                [filename,pathname] = uigetfile('*.*','Pick an Image');
                image = strcat(pathname,filename);
                a=imread(image);
                imshow(a, 'Parent', app.UIAxes_3);
                app.image1 =a;

        end

        % Value changed function: RowSpinner
        function RowSpinnerValueChanged(app, event)
            value = app.RowSpinner.Value;
            app.filterbound1=value;
        end

        % Value changed function: ColumnSpinner
        function ColumnSpinnerValueChanged(app, event)
            value = app.ColumnSpinner.Value;
            app.filterbound2=value;
        end

        % Button pushed function: MeanFilterButton
        function MeanFilterButtonPushed(app, event)
                img = app.image2;
mean_filtered_image = imfilter(img, fspecial('average', [app.filterbound1, app.filterbound2]));
imshow(mean_filtered_image, 'Parent', app.UIAxes2_2);

        end

        % Button pushed function: AdaptivefilterButton
        function AdaptivefilterButtonPushed(app, event)
            
                  J=im2double(app.image2);
                  app.image2=wiener2(J,[app.filterbound1,app.filterbound2]);
                  imshow(app.image2,'Parent',app.UIAxes2_2)
        end

        % Button pushed function: GeometricMeanFilterButton
        function GeometricMeanFilterButtonPushed(app, event)
            
                  Kr=app.filterbound1;
                  Kc=app.filterbound2;
                  J=im2double(app.image2);
                  app.image2=exp(imfilter(log(J),ones(Kc,Kr),"replicate")).^(1/(Kr*Kc));
                  imshow(app.image2,'Parent',app.UIAxes2_2)
        end

        % Button pushed function: HarmonicFilterButton
        function HarmonicFilterButtonPushed(app, event)
            
                  Kr=app.filterbound1;
                  Kc=app.filterbound2;
                  J=im2double(app.image2);
                  app.image2=(Kr*Kc)./imfilter(1./(J+eps),ones(Kr,Kc),"replicate");
                  imshow(app.image2,'Parent',app.UIAxes2_2)
        end

        % Button pushed function: MinfilterButton
        function MinfilterButtonPushed(app, event)
                img = app.image2;
min_filtered_image = ordfilt2(img, 1, true(app.filterbound1, app.filterbound2));
imshow(min_filtered_image, 'Parent', app.UIAxes2_2);
        end

        % Button pushed function: MaxFilterButton
        function MaxFilterButtonPushed(app, event)
               img = app.image2;
max_filtered_image = ordfilt2(img, app.filterbound1 * app.filterbound2, true(app.filterbound1, app.filterbound2));
imshow(max_filtered_image, 'Parent', app.UIAxes2_2);
        end

        % Button pushed function: MedianFilterButton_2
        function MedianFilterButton_2Pushed(app, event)
                img=app.image2;
                filtered_image = medfilt2(img, [app.filterbound1, app.filterbound2]);
                imshow(filtered_image,'Parent',app.UIAxes2_2)
        end

        % Button pushed function: Image71Button
        function Image71ButtonPushed(app, event)
                image = app.image3;
                [~ ,~ ,p]=size(image);
                if p==3
                image = rgb2gray(image);                                    
                end
                new_image=zeros(size(image));                  
                modif_A=padarray(image,[1 1]);                            
                x=(1:4)';
                y=[1:4]';
                for i=1:size(modif_A,1)-3
                   for j=1:size(modif_A,2)-3
                        axes=reshape(modif_A(i+x-1,j+y-1),[],1);          
                        new_image(i,j)=median(axes);
                    end
                end
                new_image2=uint8(new_image);
                [~,noise] = wiener2(new_image2,[27,27]);                     
                LEN =97;
                THETA =315;
                PSF = fspecial('motion', LEN, THETA);
                d_im4 = deconvwnr(new_image2,PSF,noise);
                new_image1=fftshift(fft2(d_im4));                            
                [x,y]=size(image);          
                matrix=ones(x,y);
                matrix(:,345)=0;                                           
                matrix(345,:)=0;
                matrix(325:370,325:370)=1;
                bf=new_image1.*matrix;
                s=ifft2(bf);                                                
                newimage=mat2gray((1+abs(s)));
                imshow(image,'Parent',app.UIAxes_4);                         
                imshow(newimage,'Parent',app.UIAxes_5);
        end

        % Button pushed function: Image72Button
        function Image72ButtonPushed(app, event)
                image = app.image3;
                [~ ,~ ,p]=size(image);
                if p==3
                image = rgb2gray(image);
                end
                new_image=zeros(size(image));
                modif_A=padarray(image,[1 1]);
                x=(1:4)';
                y=(1:4)';
                for i=1:size(modif_A,1)-3
                   for j=1:size(modif_A,2)-3
                        axes=reshape(modif_A(i+x-1,j+y-1),[],1);
                         new_image(i,j)=median(axes);
                    end
                end
                new_image2=uint8(new_image);
                [J,noise] = wiener2(new_image2,[27,27]);
                LEN =97;
                THETA =315;
                PSF = fspecial('motion', LEN, THETA);
                d_im4 = deconvwnr(new_image2,PSF,noise);
                new_image1=fftshift(fft2(d_im4));
                [x,y]=size(image);
                matrix=ones(x,y);
                matrix(:,345)=0;
                matrix(345,:)=0;
                matrix(325:370,325:370)=1;
                bf=new_image1.*matrix;
                s=ifft2(bf);
                newimage=mat2gray((1+abs(s)));
                imshow(image,'Parent',app.UIAxes_4);
                imshow(newimage,'Parent',app.UIAxes_5);
        end

        % Button pushed function: Image73Button
        function Image73ButtonPushed(app, event)
                image = app.image3;
                [m ,n ,p]=size(image);
                if p==3
                image = rgb2gray(image);
                end
                new_image=zeros(size(image));
                modif_A=padarray(image,[1 1]);
                x=[1:4]';
                y=[1:4]';
                for i=1:size(modif_A,1)-3
                   for j=1:size(modif_A,2)-3
                        axes=reshape(modif_A(i+x-1,j+y-1),[],1);
                         new_image(i,j)=median(axes);
                    end
                end
                new_image2=uint8(new_image);
                f_image=fftshift(fft2(new_image2));
                [x,y,z]=size(image);
                matrix=ones(x,y,z);
                matrix(:,344:346)=0;
                matrix(344:346,:)=0;
                matrix(325:370,325:370)=1;
                bf=f_image.*matrix;
                s=ifft2(bf);
                newimage=mat2gray((1+abs(s))); 
                [J,noise] = wiener2(newimage,[20,20]);
                LEN =97;
                THETA =315;
                PSF = fspecial('motion', LEN, THETA);
                d_im4=deconvwnr(newimage,PSF,noise);
                imshow(image,'Parent',app.UIAxes_4);
                imshow(d_im4,'Parent',app.UIAxes_5);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
                [filename,pathname] = uigetfile('*.*','Pick an Image');
                image = strcat(pathname,filename);
                a=imread(image);
                imshow(a, 'Parent', app.UIAxes_4);
                app.image3 =a;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            global image
            [filename pathname] = uigetfile({'*.png;*.tif'});  
            fullpathname = strcat(pathname,filename);         
            image = imread(fullpathname);         
            imshow(image,'parent',app.UIAxes_6);
            [row_size,column_size,rgb] = size(image);
            global thresholdvalue
            global kernelsize
            thresholdvalue = 0.6;
            kernelsize = 3;
            app.kernelsize.Value = 3;
            app.thresholdvalue.Value = 0.6;
        end

        % Button pushed function: DilationButton
        function DilationButtonPushed(app, event)
             global kernelsize
            img = getimage(app.UIAxes_6);
            image = double(img);
            SE = strel('disk', kernelsize);
            kernel = imdilate(image, SE);
            dilationimage=uint8(kernel);
            imshow(dilationimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: ErosionButton
        function ErosionButtonPushed(app, event)
            global kernelsize
            % global img;
            img = getimage(app.UIAxes_6);     
            image = double(img);
            SE = strel('disk', kernelsize);
            kernel = imerode(image, SE);
            erosionimage=uint8(kernel);
            imshow(erosionimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: OpeningButton
        function OpeningButtonPushed(app, event)
            global kernelsize
            img = getimage(app.UIAxes_6);  
            image = double(img);
            SE = strel('disk', kernelsize);
            kernel = imopen(image, SE);
            openingimage=uint8(kernel);
            imshow(openingimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: ClosingButton
        function ClosingButtonPushed(app, event)
            global kernelsize
            img = getimage(app.UIAxes_6);  
            image = double(img);
            SE = strel('disk', kernelsize);
            kernel = imclose(image, SE);
            closingimage=uint8(kernel);
            imshow(closingimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: MorphologicalGradientButton
        function MorphologicalGradientButtonPushed(app, event)
            global kernelsize
            global gradientimage
            % global img;
            img = getimage(app.UIAxes_6);     
            image = double(img);
            SE = strel('disk', kernelsize);
            kernel = imerode(image, SE);
            erosionimage=uint8(kernel);
            kerneldilate = imdilate(image, SE);
            dilationimage=uint8(kerneldilate);
            gradientimage = dilationimage - erosionimage;
            imshow(gradientimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
             global gradientimage
            %global img
            img = getimage(app.UIAxes_6);   
            segmentedimage = gradientimage + img;
            imshow(segmentedimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: ThresholdingButton
        function ThresholdingButtonPushed(app, event)
            global thresholdvalue
            img = getimage(app.UIAxes_6);   
            thresholdingimage=imbinarize(img,thresholdvalue);
            imshow(thresholdingimage,'parent',app.UIAxes_7);
        end

        % Button pushed function: THatTransformationButton
        function THatTransformationButtonPushed(app, event)
            
            global kernelsize
            % Read image from UIAxes_6
            img = getimage(app.UIAxes_6);
            image = double(img);
            kernelsize = round(double(kernelsize));

        
            % Convert RGB to grayscale using im2gray
            image_gray = im2gray(uint8(image));
        
            % Define a square structuring element for opening operation
            se = strel('square', kernelsize);
        
            % Perform opening operation using imopen
            opening_image = imopen(image_gray, se);
        
            % T-hat transformation
            transformation_image = image_gray - opening_image;
                    
            % Display the result in UIAxes_7
            imshow(transformation_image,'parent',app.UIAxes_7);
        end

        % Button pushed function: Close30Open100Gradient3Button
        function Close30Open100Gradient3ButtonPushed(app, event)
            global kernelsize;
             % Read image from UIAxes_6
            img = getimage(app.UIAxes_6);
            image = double(img);
        
            % Disk çekirdekli kapanma (Kernel Size = 30)
            se_closing = strel('disk', 30);
            disk_kernel_closing = imclose(image, se_closing);
        
            % Disk çekirdekli açılma (Kernel Size = 100)
            se_opening = strel('disk', 100);
            disk_kernel_opening = imopen(disk_kernel_closing, se_opening);
        
            % Kare çekirdekli gradyan (Kernel Size = 3)
            se_gradient = strel('square', 3);
        
            % Calculate gradient using imgradient
            [Gx, Gy] = imgradient(disk_kernel_opening);
        
            % Convert the results to uint8
            Gx = uint8(Gx);
            Gy = uint8(Gy);
            imshow(Gx, 'parent', app.UIAxes_7);

        end

        % Button pushed function: ThresholdedThatButton
        function ThresholdedThatButtonPushed(app, event)
            global kernelsize
            global thresholdvalue
            
            % Görüntüyü alma
            img = getimage(app.UIAxes_6);
            
            % Görüntüyü double türüne dönüştürme
            image = double(img);
            
            % Disk şeklinde bir structuring element oluşturma
            SE = strel('disk', kernelsize);
            
            % Opening işlemi
            kernel = imopen(image, SE);
            
            % Opening sonucunu uint8 türüne dönüştürme
            openingimage = uint8(kernel);
            
            % T-hat dönüşümü
            t_hat_transformation = image - kernel;
            
            % T-hat dönüşümünü uint8 türüne dönüştürme
            t_hat_transformation = uint8(t_hat_transformation);
            
            % Thresholding işlemi
            thresholded_t_hat = t_hat_transformation > thresholdvalue * 255;
            
            % Görselleştirme
            imshow(thresholded_t_hat, 'parent', app.UIAxes_7);
        end

        % Value changed function: kernelsize
        function kernelsizeValueChanged(app, event)
            global kernelsize
            kernelsize = app.kernelsize.Value;
        end

        % Value changed function: thresholdvalue
        function thresholdvalueValueChanged(app, event)
            global thresholdvalue          
            thresholdvalue = app.thresholdvalue.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MuratKILCIUIFigure = uifigure('Visible', 'off');
            app.MuratKILCIUIFigure.Name = 'Murat KILCI';
            app.MuratKILCIUIFigure.Icon = fullfile(pathToMLAPP, 'image-processing.png');
            app.TabGroup = uitabgroup(app.MuratKILCIUIFigure);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.uni_logo = uiimage(app.DashboardTab);
            app.uni_logo.ImageClickedFcn = createCallbackFcn(app, @uni_logoImageClicked, true);
            app.uni_logo.ImageSource = fullfile(pathToMLAPP, 'logo_english_university.png');
            app.Image2 = uiimage(app.DashboardTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'qrcode.png');
            app.help_logo = uiimage(app.DashboardTab);
            app.help_logo.ImageClickedFcn = createCallbackFcn(app, @help_logoImageClicked, true);
            app.help_logo.ImageSource = fullfile(pathToMLAPP, 'icons8-supporting-64.png');
            app.youtube_logo = uiimage(app.DashboardTab);
            app.youtube_logo.ImageClickedFcn = createCallbackFcn(app, @youtube_logoImageClicked, true);
            app.youtube_logo.ImageSource = fullfile(pathToMLAPP, 'icons8-youtube-48.png');
            app.instagram_logo = uiimage(app.DashboardTab);
            app.instagram_logo.ImageClickedFcn = createCallbackFcn(app, @instagram_logoImageClicked, true);
            app.instagram_logo.ImageSource = fullfile(pathToMLAPP, 'icons8-instagram-48.png');
            app.linkedin_logo = uiimage(app.DashboardTab);
            app.linkedin_logo.ImageClickedFcn = createCallbackFcn(app, @linkedin_logoImageClicked, true);
            app.linkedin_logo.ImageSource = fullfile(pathToMLAPP, 'icons8-linkedin-48.png');
            app.github_logo = uiimage(app.DashboardTab);
            app.github_logo.ImageClickedFcn = createCallbackFcn(app, @github_logoImageClicked, true);
            app.github_logo.ImageSource = fullfile(pathToMLAPP, 'icons8-github-48.png');
            app.logout_logo = uiimage(app.DashboardTab);
            app.logout_logo.ImageClickedFcn = createCallbackFcn(app, @logout_logoImageClicked, true);
            app.logout_logo.ImageSource = fullfile(pathToMLAPP, '1053210.png');
            app.HW1Tab = uitab(app.TabGroup);
            app.HW1Tab.Title = 'HW1';
            app.UIAxes2 = uiaxes(app.HW1Tab);
            title(app.UIAxes2, 'Convert Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.UIAxes = uiaxes(app.HW1Tab);
            title(app.UIAxes, 'Orjinal Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Label = uilabel(app.HW1Tab);
            app.Label.Text = '';
            app.ConvertButton = uibutton(app.HW1Tab, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.ChooseImageButton = uibutton(app.HW1Tab, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ROWKnobLabel = uilabel(app.HW1Tab);
            app.ROWKnobLabel.Text = 'ROW';
            app.ROWKnob = uiknob(app.HW1Tab, 'continuous');
            app.ROWKnob.Limits = [0 25];
            app.COLLUMNKnobLabel = uilabel(app.HW1Tab);
            app.COLLUMNKnobLabel.Text = 'COLLUMN';
            app.COLLUMNKnob = uiknob(app.HW1Tab, 'continuous');
            app.COLLUMNKnob.Limits = [0 25];
            app.grayscaleSwitchLabel = uilabel(app.HW1Tab);
            app.grayscaleSwitchLabel.Text = 'grayscale';
            app.grayscaleSwitch = uiswitch(app.HW1Tab, 'slider');
            app.HW2Tab = uitab(app.TabGroup);
            app.HW2Tab.Title = 'HW2';
            app.inputImage = uiaxes(app.HW2Tab);
            title(app.inputImage, 'Input Image')
            app.inputImage.XTick = [];
            app.inputImage.YTick = [];
            app.inputImage.Visible = 'off';
            app.outputImage = uiaxes(app.HW2Tab);
            title(app.outputImage, 'Output Image')
            app.outputImage.XTick = [];
            app.outputImage.YTick = [];
            app.outputImage.Visible = 'off';
            app.CHOOSEFILEButton = uibutton(app.HW2Tab, 'push');
            app.CHOOSEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEFILEButtonPushed, true);
            app.CHOOSEFILEButton.Text = 'CHOOSE FILE';
            app.REFLECTButtonGroup = uibuttongroup(app.HW2Tab);
            app.REFLECTButtonGroup.Title = 'REFLECT';
            app.HORIZONTALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.HORIZONTALButton.Text = 'HORIZONTAL';
            app.HORIZONTALButton.Value = true;
            app.VERTICALButton = uiradiobutton(app.REFLECTButtonGroup);
            app.VERTICALButton.Text = 'VERTICAL';
            app.CROSSEDButton = uiradiobutton(app.REFLECTButtonGroup);
            app.CROSSEDButton.Text = 'CROSSED';
            app.REFLECTButton = uibutton(app.HW2Tab, 'push');
            app.REFLECTButton.ButtonPushedFcn = createCallbackFcn(app, @REFLECTButtonPushed, true);
            app.REFLECTButton.Text = 'REFLECT';
            app.SHIFTButton = uibutton(app.HW2Tab, 'push');
            app.SHIFTButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTButtonPushed, true);
            app.SHIFTButton.Text = 'SHIFT';
            app.RESIZEButton = uibutton(app.HW2Tab, 'push');
            app.RESIZEButton.ButtonPushedFcn = createCallbackFcn(app, @RESIZEButtonPushed, true);
            app.RESIZEButton.Text = 'RESIZE';
            app.CROPButton = uibutton(app.HW2Tab, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.RGBHSIButton = uibutton(app.HW2Tab, 'push');
            app.RGBHSIButton.ButtonPushedFcn = createCallbackFcn(app, @RGBHSIButtonPushed, true);
            app.RGBHSIButton.Text = 'RGB -> HSI';
            app.SHIFTIMAGERIGHTEditFieldLabel = uilabel(app.HW2Tab);
            app.SHIFTIMAGERIGHTEditFieldLabel.Text = 'SHIFT IMAGE  RIGHT';
            app.SHIFTIMAGERIGHTEditField = uieditfield(app.HW2Tab, 'numeric');
            app.SHIFTIMAGERIGHTEditField.Limits = [1 Inf];
            app.SHIFTIMAGERIGHTEditField.Value = 1;
            app.SHIFTIMAGEDOWNEditFieldLabel = uilabel(app.HW2Tab);
            app.SHIFTIMAGEDOWNEditFieldLabel.Text = 'SHIFT IMAGE  DOWN';
            app.SHIFTIMAGEDOWNEditField = uieditfield(app.HW2Tab, 'numeric');
            app.SHIFTIMAGEDOWNEditField.Limits = [1 Inf];
            app.SHIFTIMAGEDOWNEditField.Value = 1;
            app.RESIZEIMAGEWIDTHEditFieldLabel = uilabel(app.HW2Tab);
            app.RESIZEIMAGEWIDTHEditFieldLabel.Text = 'RESIZE IMAGE WIDTH ';
            app.RESIZEIMAGEWIDTHEditField = uieditfield(app.HW2Tab, 'numeric');
            app.RESIZEIMAGEWIDTHEditField.Limits = [1 Inf];
            app.RESIZEIMAGEWIDTHEditField.Value = 1;
            app.RESIZEIMAGEHEIGHTEditFieldLabel = uilabel(app.HW2Tab);
            app.RESIZEIMAGEHEIGHTEditFieldLabel.Text = 'RESIZE IMAGE HEIGHT';
            app.RESIZEIMAGEHEIGHTEditField = uieditfield(app.HW2Tab, 'numeric');
            app.RESIZEIMAGEHEIGHTEditField.Limits = [1 Inf];
            app.RESIZEIMAGEHEIGHTEditField.Value = 1;
            app.CROPIMAGEX1EditFieldLabel = uilabel(app.HW2Tab);
            app.CROPIMAGEX1EditFieldLabel.Text = 'CROP IMAGE X1';
            app.CROPIMAGEX1EditField = uieditfield(app.HW2Tab, 'numeric');
            app.CROPIMAGEX1EditField.Limits = [1 Inf];
            app.CROPIMAGEX1EditField.Value = 1;
            app.CROPIMAGEY1EditFieldLabel = uilabel(app.HW2Tab);
            app.CROPIMAGEY1EditFieldLabel.Text = 'CROP IMAGE Y1';
            app.CROPIMAGEY1EditField = uieditfield(app.HW2Tab, 'numeric');
            app.CROPIMAGEY1EditField.Limits = [1 Inf];
            app.CROPIMAGEY1EditField.Value = 1;
            app.CROPIMAGEX2EditFieldLabel = uilabel(app.HW2Tab);
            app.CROPIMAGEX2EditFieldLabel.Text = 'CROP IMAGE X2';
            app.CROPIMAGEX2EditField = uieditfield(app.HW2Tab, 'numeric');
            app.CROPIMAGEX2EditField.Limits = [1 Inf];
            app.CROPIMAGEX2EditField.Value = 1;
            app.CROPIMAGEY2EditFieldLabel = uilabel(app.HW2Tab);
            app.CROPIMAGEY2EditFieldLabel.Text = 'CROP IMAGE Y2';
            app.CROPIMAGEY2EditField = uieditfield(app.HW2Tab, 'numeric');
            app.CROPIMAGEY2EditField.Limits = [1 Inf];
            app.CROPIMAGEY2EditField.Value = 1;
            app.HW3Tab = uitab(app.TabGroup);
            app.HW3Tab.Title = 'HW3';
            app.hw3InputImage = uiimage(app.HW3Tab);
            app.hw3InputImage.Visible = 'off';
            app.hw3OutputImage = uiimage(app.HW3Tab);
            app.hw3OutputImage.Visible = 'off';
            app.SelectPictureButton = uibutton(app.HW3Tab, 'push');
            app.SelectPictureButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPictureButtonPushed, true);
            app.SelectPictureButton.Text = 'Select Picture';
            app.TransferFunctionConvertButton = uibutton(app.HW3Tab, 'push');
            app.TransferFunctionConvertButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFunctionConvertButtonPushed, true);
            app.TransferFunctionConvertButton.Text = 'Transfer Function Convert';
            app.HistogramStretchingButton = uibutton(app.HW3Tab, 'push');
            app.HistogramStretchingButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramStretchingButtonPushed, true);
            app.HistogramStretchingButton.Text = 'Histogram Stretching';
            app.HistogramEqualizationButton = uibutton(app.HW3Tab, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.WhiteLevelSliderLabel = uilabel(app.HW3Tab);
            app.WhiteLevelSliderLabel.Text = 'White Level';
            app.WhiteLevelSlider = uislider(app.HW3Tab);
            app.WhiteLevelSlider.Limits = [0 255];
            app.WhiteLevelSlider.Value = 255;
            app.BlackLevelSliderLabel = uilabel(app.HW3Tab);
            app.BlackLevelSliderLabel.Text = 'Black Level';
            app.BlackLevelSlider = uislider(app.HW3Tab);
            app.BlackLevelSlider.Limits = [0 255];
            app.ShowHistogramButton = uibutton(app.HW3Tab, 'push');
            app.ShowHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramButtonPushed, true);
            app.ShowHistogramButton.Text = 'Show Histogram';
            app.HW4Tab = uitab(app.TabGroup);
            app.HW4Tab.Title = 'HW4';
            app.UIAxes_2 = uiaxes(app.HW4Tab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.MedianFilterButton = uibutton(app.HW4Tab, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.AverageFilterButton = uibutton(app.HW4Tab, 'push');
            app.AverageFilterButton.ButtonPushedFcn = createCallbackFcn(app, @AverageFilterButtonPushed, true);
            app.AverageFilterButton.Text = 'Average Filter';
            app.MaximumFilterButton = uibutton(app.HW4Tab, 'push');
            app.MaximumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaximumFilterButtonPushed, true);
            app.MaximumFilterButton.Text = 'Maximum Filter';
            app.MinimumFilterButton = uibutton(app.HW4Tab, 'push');
            app.MinimumFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinimumFilterButtonPushed, true);
            app.MinimumFilterButton.Text = 'Minimum Filter';
            app.SelectPictureButton_2 = uibutton(app.HW4Tab, 'push');
            app.SelectPictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectPictureButton_2Pushed, true);
            app.SelectPictureButton_2.Text = 'Select Picture';
            app.MinColumnFilterEditField = uieditfield(app.HW4Tab, 'numeric');
            app.MinColumnFilterEditField.Limits = [1 Inf];
            app.MinColumnFilterEditField.Value = 1;
            app.MinRowFilterEditField = uieditfield(app.HW4Tab, 'numeric');
            app.MinRowFilterEditField.Limits = [1 Inf];
            app.MinRowFilterEditField.Value = 1;
            app.hw4_image_output = uiimage(app.HW4Tab);
            app.hw4_image_output.Visible = 'off';
            app.hw4_image = uiimage(app.HW4Tab);
            app.hw4_image.Visible = 'off';
            app.ROWLabel = uilabel(app.HW4Tab);
            app.ROWLabel.Text = 'ROW';
            app.COLUMNLabel = uilabel(app.HW4Tab);
            app.COLUMNLabel.Text = 'COLUMN';
            app.LaplacianButton = uibutton(app.HW4Tab, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.SharpenedImageButton = uibutton(app.HW4Tab, 'push');
            app.SharpenedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenedImageButtonPushed, true);
            app.SharpenedImageButton.Text = 'Sharpened Image';
            app.SobelGradientButton = uibutton(app.HW4Tab, 'push');
            app.SobelGradientButton.ButtonPushedFcn = createCallbackFcn(app, @SobelGradientButtonPushed, true);
            app.SobelGradientButton.Text = 'Sobel Gradient';
            app.SobelSmoothButton = uibutton(app.HW4Tab, 'push');
            app.SobelSmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SobelSmoothButtonPushed, true);
            app.SobelSmoothButton.Text = 'Sobel Smooth';
            app.MaskButton = uibutton(app.HW4Tab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.MaskImageButton = uibutton(app.HW4Tab, 'push');
            app.MaskImageButton.ButtonPushedFcn = createCallbackFcn(app, @MaskImageButtonPushed, true);
            app.MaskImageButton.Text = 'Mask+Image';
            app.HW5Tab = uitab(app.TabGroup);
            app.HW5Tab.Title = 'HW5';
            app.hw5_input_img = uiaxes(app.HW5Tab);
            title(app.hw5_input_img, 'ORGINAL IMAGE')
            app.hw5_input_img.XTick = [];
            app.hw5_input_img.YTick = [];
            app.hw5_input_img.Visible = 'off';
            app.hw5_img2 = uiaxes(app.HW5Tab);
            app.hw5_img2.XTick = [];
            app.hw5_img2.YTick = [];
            app.hw5_img2.Visible = 'off';
            app.hw5_img1 = uiaxes(app.HW5Tab);
            app.hw5_img1.XTick = [];
            app.hw5_img1.YTick = [];
            app.hw5_img1.Visible = 'off';
            app.CHOOSEIMAGEButton = uibutton(app.HW5Tab, 'push');
            app.CHOOSEIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEIMAGEButtonPushed, true);
            app.CHOOSEIMAGEButton.Text = 'CHOOSE IMAGE';
            app.BUTTERWORTHHIGHPASSFILTERButton = uibutton(app.HW5Tab, 'push');
            app.BUTTERWORTHHIGHPASSFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @BUTTERWORTHHIGHPASSFILTERButtonPushed, true);
            app.BUTTERWORTHHIGHPASSFILTERButton.Text = 'BUTTERWORTH HIGH PASS FILTER';
            app.BUTTERWORTHNOTCHREJECTFILTERButton = uibutton(app.HW5Tab, 'push');
            app.BUTTERWORTHNOTCHREJECTFILTERButton.ButtonPushedFcn = createCallbackFcn(app, @BUTTERWORTHNOTCHREJECTFILTERButtonPushed, true);
            app.BUTTERWORTHNOTCHREJECTFILTERButton.Text = 'BUTTERWORTH NOTCH REJECT FILTER';
            app.HW6Tab = uitab(app.TabGroup);
            app.HW6Tab.Title = 'HW6';
            app.UIAxes_3 = uiaxes(app.HW6Tab);
            title(app.UIAxes_3, 'Orjinal Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.UIAxes2_2 = uiaxes(app.HW6Tab);
            title(app.UIAxes2_2, 'Convert Image')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_2.Visible = 'off';
            app.DropDown = uidropdown(app.HW6Tab);
            app.DropDown.Items = {'Noise', 'Salt and Pepper', 'Salt', 'Pepper', 'Gaussian', 'Uniform', 'Erlang (Gamma)', 'Rayleigh', 'Exponential', 'Log Normal', 'Periodic'};
            app.DropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 0;
            app.SelectPictureButton_3 = uibutton(app.HW6Tab, 'push');
            app.SelectPictureButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectPictureButton_3Pushed, true);
            app.SelectPictureButton_3.Text = 'Select Picture';
            app.RowSpinnerLabel = uilabel(app.HW6Tab);
            app.RowSpinnerLabel.Text = 'Row:';
            app.RowSpinner = uispinner(app.HW6Tab);
            app.RowSpinner.ValueChangedFcn = createCallbackFcn(app, @RowSpinnerValueChanged, true);
            app.ColumnSpinnerLabel = uilabel(app.HW6Tab);
            app.ColumnSpinnerLabel.Text = 'Column:';
            app.ColumnSpinner = uispinner(app.HW6Tab);
            app.ColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnSpinnerValueChanged, true);
            app.MeanFilterButton = uibutton(app.HW6Tab, 'push');
            app.MeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanFilterButtonPushed, true);
            app.MeanFilterButton.Text = 'Mean Filter';
            app.MedianFilterButton_2 = uibutton(app.HW6Tab, 'push');
            app.MedianFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButton_2Pushed, true);
            app.MedianFilterButton_2.Text = 'Median Filter';
            app.MaxFilterButton = uibutton(app.HW6Tab, 'push');
            app.MaxFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MaxFilterButtonPushed, true);
            app.MaxFilterButton.Text = 'Max Filter';
            app.MinfilterButton = uibutton(app.HW6Tab, 'push');
            app.MinfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MinfilterButtonPushed, true);
            app.MinfilterButton.Text = 'Min filter';
            app.HarmonicFilterButton = uibutton(app.HW6Tab, 'push');
            app.HarmonicFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HarmonicFilterButtonPushed, true);
            app.HarmonicFilterButton.Text = 'Harmonic Filter';
            app.GeometricMeanFilterButton = uibutton(app.HW6Tab, 'push');
            app.GeometricMeanFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GeometricMeanFilterButtonPushed, true);
            app.GeometricMeanFilterButton.Text = 'Geometric Mean Filter';
            app.AdaptivefilterButton = uibutton(app.HW6Tab, 'push');
            app.AdaptivefilterButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptivefilterButtonPushed, true);
            app.AdaptivefilterButton.Text = 'Adaptive filter';
            app.HW7Tab = uitab(app.TabGroup);
            app.HW7Tab.Title = 'HW7';
            app.UIAxes_4 = uiaxes(app.HW7Tab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.HW7Tab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.Image71Button = uibutton(app.HW7Tab, 'push');
            app.Image71Button.ButtonPushedFcn = createCallbackFcn(app, @Image71ButtonPushed, true);
            app.Image71Button.Text = 'Image 7.1';
            app.Image72Button = uibutton(app.HW7Tab, 'push');
            app.Image72Button.ButtonPushedFcn = createCallbackFcn(app, @Image72ButtonPushed, true);
            app.Image72Button.Text = 'Image 7.2';
            app.Image73Button = uibutton(app.HW7Tab, 'push');
            app.Image73Button.ButtonPushedFcn = createCallbackFcn(app, @Image73ButtonPushed, true);
            app.Image73Button.Text = 'Image 7.3';
            app.SelectImageButton = uibutton(app.HW7Tab, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.HW8Tab = uitab(app.TabGroup);
            app.HW8Tab.Title = 'HW8';
            app.UIAxes_6 = uiaxes(app.HW8Tab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.HW8Tab);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.Visible = 'off';
            app.OriginalImageLabel = uilabel(app.HW8Tab);
            app.OriginalImageLabel.Text = 'Original Image';
            app.FilteredImageLabel = uilabel(app.HW8Tab);
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.LoadImageButton = uibutton(app.HW8Tab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DilationButton = uibutton(app.HW8Tab, 'push');
            app.DilationButton.ButtonPushedFcn = createCallbackFcn(app, @DilationButtonPushed, true);
            app.DilationButton.Text = 'Dilation';
            app.ErosionButton = uibutton(app.HW8Tab, 'push');
            app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
            app.ErosionButton.Text = 'Erosion';
            app.OpeningButton = uibutton(app.HW8Tab, 'push');
            app.OpeningButton.ButtonPushedFcn = createCallbackFcn(app, @OpeningButtonPushed, true);
            app.OpeningButton.Text = 'Opening';
            app.ClosingButton = uibutton(app.HW8Tab, 'push');
            app.ClosingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosingButtonPushed, true);
            app.ClosingButton.Text = 'Closing';
            app.ThresholdingButton = uibutton(app.HW8Tab, 'push');
            app.ThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdingButtonPushed, true);
            app.ThresholdingButton.Text = 'Thresholding';
            app.THatTransformationButton = uibutton(app.HW8Tab, 'push');
            app.THatTransformationButton.ButtonPushedFcn = createCallbackFcn(app, @THatTransformationButtonPushed, true);
            app.THatTransformationButton.Text = 'T Hat Transformation';
            app.Close30Open100Gradient3Button = uibutton(app.HW8Tab, 'push');
            app.Close30Open100Gradient3Button.ButtonPushedFcn = createCallbackFcn(app, @Close30Open100Gradient3ButtonPushed, true);
            app.Close30Open100Gradient3Button.Text = 'Close(30)-Open(100)-Gradient(3)';
            app.ThresholdedThatButton = uibutton(app.HW8Tab, 'push');
            app.ThresholdedThatButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdedThatButtonPushed, true);
            app.ThresholdedThatButton.Text = 'Thresholded T hat ';
            app.kernelsize = uieditfield(app.HW8Tab, 'numeric');
            app.kernelsize.ValueChangedFcn = createCallbackFcn(app, @kernelsizeValueChanged, true);
            app.thresholdvalue = uieditfield(app.HW8Tab, 'numeric');
            app.thresholdvalue.ValueChangedFcn = createCallbackFcn(app, @thresholdvalueValueChanged, true);
            app.EditField = uieditfield(app.HW8Tab, 'text');
            app.EditField.Value = 'Threshold Value';
            app.EditField_2 = uieditfield(app.HW8Tab, 'text');
            app.EditField_2.Value = 'Kernel Size';
            app.MorphologicalGradientButton = uibutton(app.HW8Tab, 'push');
            app.MorphologicalGradientButton.ButtonPushedFcn = createCallbackFcn(app, @MorphologicalGradientButtonPushed, true);
            app.MorphologicalGradientButton.Text = 'Morphological Gradient';
            app.SegmentationButton = uibutton(app.HW8Tab, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation ';
            % Show the figure after all components are created
            app.MuratKILCIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuratKILCIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuratKILCIUIFigure)
        end
    end
end